classdef ilk_inv_gui_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GravityDataEditFieldLabel       matlab.ui.control.Label
        GravityDataEditField            matlab.ui.control.EditField
        BrowseButton                    matlab.ui.control.Button
        dyEditFieldLabel                matlab.ui.control.Label
        dyEditField                     matlab.ui.control.EditField
        dxEditField_2Label              matlab.ui.control.Label
        dxEditField_2                   matlab.ui.control.EditField
        SlabThicknesskmEditFieldLabel   matlab.ui.control.Label
        SlabThicknesskmEditField        matlab.ui.control.EditField
        UncertaintyPercentageEditFieldLabel  matlab.ui.control.Label
        UncertaintyPercentageEditField  matlab.ui.control.EditField
        RunButton                       matlab.ui.control.Button
        NegativedensitycontrastCheckBox  matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
                %sol ustte property den public property ekleyerek yaptık bunu
        FILE1 %secilen ilk dosyayi FILE1 e atar.
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function runbutton(app, event)
             %button a gelip callback kisminda function a runbutton yazdik.
            
            Control=0; %kontrol degerı butun degerlerın girildigini belirtir 4 olursa program run eder.
            
            dx=str2double(app.dxEditField_2.Value); %dxx ve dyy yi buradan tanimladim. dxx ve dyy degerlerini almak icin burasi onemli
            %f=errordlg(num2str(dxx),'It will work') %Girilen degeri gosterecek calisip calismadigini anlamak icin
            if isnan(dx) %NaN varsa hata ver
            f=errordlg('dx is empty','Warning!')
            else
                Control=Control+1;
            end
            dy=str2double(app.dyEditField.Value);
            %f=errordlg(num2str(dyy),'It will work')
            if isnan(dy) %NaN varsa hata ver
            f=errordlg('dy is empty','Warning!')
             else
                Control=Control+1;
            end
            uncertainty=str2double(app.UncertaintyPercentageEditField.Value);
%             f=errordlg(num2str(dx),'It will work')
            if isnan(uncertainty) %NaN varsa hata ver
            f=errordlg('Uncertainty value is empty','Warning!')
             else
                Control=Control+1;
            end
            DEPTH_MAP=str2double(app.SlabThicknesskmEditField.Value);
%             f=errordlg(num2str(dx),'It will work')
            if isnan(DEPTH_MAP) %NaN varsa hata ver
            f=errordlg('depth value is empty','Warning!')
             else
                Control=Control+1;
            end
            
            
            f1=app.GravityDataEditField.Value;
            if isnan(f1) %NaN varsa hata ver
            f=errordlg('Gravity data is not been selected!')
            else
               Control=Control+1;
            end
            
           if Control==5
            
%INPUT
%DEPTH_MAP=Single Constant Depth (-ve for downwards, +ve for upwards)
%dx= grid spacing along x direction.
%dy= grid spacing along y direction.
%data1=xyz format gravity data x and y columns should be km.
%it=iteration number
%uncertainty of depth (percentage)
%--------------------------------------------------------------
%OUTPUT
%RHO_E=Estimated Final Density Distribution
%R=Resolution Matrix
%--------------------------------------------------------------
%NOTES
%Input should be xyz dat.
%x and y axis should be km
%Median filter automatically smooth the data
%Corners and edges of the study area is not estimated correctly
%If grid spacing is bigger, program works better.
%If there is a matrix dimension error, change the grid spacing. Grid
%Spacing X should not be too much bigger than Grid Spacing Y.

%Thickness should be positive but algorithm work with negative 
DEPTH_MAP=-1.*DEPTH_MAP;

%INPUT
 %Constant Depth Provides much Better Results
%DEPTH_MAP=load('cube_depth.dat'); %Depth Grid
GRAV_MAP=load(app.FILE1);  %Gravity anomaly

% X_DEPTH=DEPTH_MAP(:,1);
% Y_DEPTH=DEPTH_MAP(:,2);
% Z_DEPTH=DEPTH_MAP(:,3);

X_GRAV=GRAV_MAP(:,1);
Y_GRAV=GRAV_MAP(:,2);
Z_GRAV=GRAV_MAP(:,3);

 X_DEPTH=GRAV_MAP(:,1);
 Y_DEPTH=GRAV_MAP(:,2);


 %if you enter dx, dy this too small, the processing time will be increased.


%Smoothing Operation
% Z_DEPTH=smoothdata(Z_DEPTH);
% Z_GRAV=smoothdata(Z_GRAV);

%MAPPING

%DEPTH
[X_D,Y_D]=meshgrid(min(X_GRAV):dx:max(X_GRAV), min(Y_GRAV):dy:max(Y_GRAV));
zz=ones(length(X_GRAV),1)*DEPTH_MAP;
Z_D=griddata(X_GRAV,Y_GRAV,zz,X_D,Y_D);
if app.NegativedensitycontrastCheckBox.Value == true;
    Z_D=Z_D*(1000); %km to m conversion if required
else
Z_D=Z_D*(-1000); %km to m conversion if required
end
[m_Z_D,n_Z_D]=size(Z_D);
%Z_D=Z_D*(-10);
% figure;
% contourf(X_D, Y_D, Z_D)
% title('Depth Grid (km)')
% xlabel('km')
% ylabel('km')
% set(gca,'fontsize',20)

% for i=1:length(Z_D(:,1))
%     for j=1:length(Z_D(1,:))
%         if Z_D(i,j)==0
%             Z_D(i,j)=Z_D(i,j)+1;
%         end
%     end
% end

%GRAVITY
[X_G,Y_G]=meshgrid(min(X_GRAV):dx:max(X_GRAV), min(Y_GRAV):dy:max(Y_GRAV));
Z_G=griddata(X_GRAV,Y_GRAV,Z_GRAV,X_G,Y_G);
%OBSERVED GRAVITY DATA
figure;
contourf(X_G,Y_G,Z_G)
colormap jet
colorbar
title('Gravity Data')
xlabel('x-unit')
ylabel('y-unit')
set(gca,'fontsize',20)

%INITIAL DENSITY DISTRIBUTION

rho_ini=Z_G./(0.04191.*(Z_D+Z_D.*uncertainty./100)); %Observed Gravity Anomaly is used.
%rho_ini=Z_G./(0.04191*Z_D); %Observed Gravity Anomaly is used.

%Median Filter
%deliminator1=floor(length(Z_G(:,1))./5);
%deliminator2=floor(length(Z_G(1,:))./5);
%rho_ini=medfilt2(rho_ini,[deliminator1,deliminator2]);
%rho_ini=medfilt2(rho_ini,[10,10]);

%Removing Outliers
%rho_ini=smooth(rho_ini);
%rho_ini_plot=2.*rho_ini-assumed_bg;

rho_ini_plot=2.*rho_ini;

% if app.NegativedensitycontrastCheckBox.Value == true;
%     figure;
% contourf(X_G,Y_G,-1.*(rho_ini_plot))
% colormap jet
% colorbar
% title('Initial Computed Density Contrast')
% xlabel('x-unit')
% ylabel('y-unit')
% set(gca,'fontsize',20)
% else
% figure;
% contourf(X_G,Y_G,rho_ini_plot)
% colormap jet
% colorbar
% title('Initial Computed Density Contrast')
% xlabel('x-unit')
% ylabel('y-unit')
% set(gca,'fontsize',20)
% end

%CALCULATED GRAVITY
G_CALC=0.04191.*rho_ini.*(Z_D);


%INVERSION
 Delta_Z=Z_G-G_CALC;
 [m_DZ,n_DZ]=size(Delta_Z);
 G=0.04191*(Delta_Z+Delta_Z.*uncertainty./100); %Calculation of Kernel 


%INVERSION v2
[U,S,V]=svd(G);

%1
counter1=0;
counter2=0;
counter3=0;
for i=1:length(U)
    for j=1:length(V)
        if  S(i,j)<10^(-14) && S(i,j)>0
           counter1=counter1+1;
        elseif S(i,j)==0
            counter2=counter2+1;
        elseif S(i,j)~=0
            counter3=counter3+1;
        end
    end
end

['S has ',num2str(counter3),' nonzero values']

['S has ',num2str(counter2),' zero values']

['S has ',num2str(counter1),' zero to computer accuracy values']

%2 New U and V regarding to the nonzero values of S

Sp=S(1:counter3,1:counter3);
Vp=V(1:length(V),1:counter3);
Up=U(1:length(U),1:counter3);

%3 Diff between G and G2. Analysing effect of
%underdetermined/overdetermined problem
G2=Up*Sp*Vp'+Up*Sp*Vp'.*uncertainty./100;
diffG=norm(G-G2);

%4 Compute G by using Up,Sp,Vp
%Gg=Vp*inv(Sp)*Up';

%5 Compute d_RHO
 %damp=0.1; %You can change damp manually.
 damp=min(eig(G2*G2'));
 Spp=(damp.^2+Sp.^2)./(damp);
d_RHO=Vp*inv(Spp)*Up'*(Delta_Z+Delta_Z.*uncertainty./100);
%d_RHO=medfilt2(d_RHO,[floor(deliminator1/2),floor(deliminator2/2)]);


RHO_E=d_RHO; 

%Adjusting size

%DEPTH
[X_D2,Y_D2]=meshgrid(linspace(min(X_DEPTH),max(X_DEPTH),length(Sp)), linspace(min(Y_DEPTH),max(Y_DEPTH),length(Sp)));
Z_D=griddata(X_DEPTH,Y_DEPTH,zz,X_D2,Y_D2);
if app.NegativedensitycontrastCheckBox.Value == true;
    Z_D=Z_D*(1000); %km to m conversion if required
else
Z_D=Z_D*(-1000); %km to m conversion if required
end


%GRAVITY
[X_G2,Y_G2]=meshgrid(linspace(min(X_GRAV),max(X_GRAV),length(Sp)), linspace(min(Y_GRAV),max(Y_GRAV),length(Sp)));
Z_G=griddata(X_GRAV,Y_GRAV,Z_GRAV,X_G2,Y_G2);

% %INITIAL RHO rho_ini
 rho_ini=Z_G./(0.04191*(Z_D+Z_D.*uncertainty./100)); %Observed Gravity Anomaly is used.
%rho_ini=medfilt2(rho_ini,[deliminator1,deliminator2]);

%Recalculation of G_CALC regarding to the new grid spacing
G_CALC=0.04191.*rho_ini.*(Z_D+Z_D.*uncertainty./100);


k=2;

 for it=1:k
   Delta_Z=Z_G-G_CALC; 
   Delta_Z2(it)=norm(Z_G-G_CALC)


   Z_D_CALC=Z_G./(0.04191.*(RHO_E+damp));
   %Z_D_CALC=medfilt2(Z_D_CALC,[floor(deliminator1),floor(deliminator2)]);
   
   G=0.04191*Delta_Z; %Calculation of Kernel
   %G=0.04191*Z_D_CALC; %Calculation of Kernel
   
   ERR(it)=norm(Z_D-Z_D_CALC);
   Delta_Z2(it)=norm(Z_G-G_CALC);
   
   %damp=min(eig(G2*G2'))+ERR(it);
   %SVD
   [U,S,V]=svd(G);
%1 analysing svd

counter1=0;
counter2=0;
counter3=0;
for i=1:length(U)
    for j=1:length(V)
        if  S(i,j)<10^(-14) && S(i,j)>0
           counter1=counter1+1;
        elseif S(i,j)==0
            counter2=counter2+1;
        elseif S(i,j)~=0
            counter3=counter3+1;
        end
    end
end

%2
Sp=S(1:counter3,1:counter3);
Vp=V(1:length(V),1:counter3);
Up=U(1:length(U),1:counter3);

%3 Estimation of Rho_e
 Spp=(damp.^2+Sp.^2)./(damp);
RHO_E2=Vp*inv(Spp)*Up'*(Delta_Z+Delta_Z.*uncertainty./100); 
%RHO_E=medfilt2(RHO_E,[floor(deliminator1/2),floor(deliminator2/2)]);
[m_RHO_E2,n_RHO_E2]=size(RHO_E2);
RHO_E=rho_ini-max(max(abs(RHO_E2)))-RHO_E2.*uncertainty./1000;
%G_CALC2=0.04191.*RHO_E.*Z_D_CALC; 
G_CALC=0.04191.*RHO_E.*Z_D;
mean_G_CALC(it)=mean(mean(G_CALC));
mean_RHO_E(it)=mean(mean(RHO_E));
 end
 
 %RHO_E=medfilt2(RHO_E,[2,2]);
 
RHO_E_plot=2.*RHO_E;


%RHO_E=medfilt2(RHO_E,[deliminator1,deliminator2]);
% if app.NegativedensitycontrastCheckBox.Value == true;
%  figure;
%  contourf(X_G2,Y_G2,-1.*(RHO_E_plot))
%  colormap jet
% colorbar
% title('Estimated Final Density Contrast')
% xlabel('x-unit')
% ylabel('y-unit')
% set(gca,'fontsize',20)
% else
%      figure;
%  contourf(X_G2,Y_G2,RHO_E_plot)
%  colormap jet
% colorbar
% title('Estimated Final Density Contrast')
% xlabel('x-unit')
% ylabel('y-unit')
% set(gca,'fontsize',20)
% end

Gr=Up*Sp*Vp';
%R=inv(Gr'*Gr+damp^2)*Gr'*Gr;
R=Vp*Vp';

for i=1:length(R(:,1))
    for j=1:length(R(1,:))
        if R(i,j)>1
            R(i,j)=1;
        end
    end
end

%  figure;
%  imagesc(R)
%  grid on
%  set(gca, 'YMinorTick','on', 'YMinorGrid','on')
%  set(gca, 'XMinorTick','on', 'XMinorGrid','on')
%  ax = gca
% ax.GridLineStyle = '-'
% ax.GridColor = 'k'
% ax.GridAlpha = 1 % maximum line opacity
%  colormap jet
% colorbar
% title('Resolution Matrix')
% xlabel('Data Index')
% ylabel('Data Index')
% axis equal
% set(gca,'fontsize',20)


Cov=inv(Gr)*cov(Delta_Z)*inv(Gr');

%  figure;
%  imagesc(Cov)
%  grid on
%  set(gca, 'YMinorTick','on', 'YMinorGrid','on')
%  set(gca, 'XMinorTick','on', 'XMinorGrid','on')
%  ax = gca
% ax.GridLineStyle = '-'
% ax.GridColor = 'k'
% ax.GridAlpha = 1 % maximum line opacity
%  colormap jet
%  axis equal
% colorbar
% title('Covariance Matrix')
% xlabel('Data Index')
% ylabel('Data Index')
% set(gca,'fontsize',20)

%HOW ESTIMATED DATA FIT 
Z_G_CALC5=0.04191.*(mean(mean(Z_D))).*RHO_E;

 figure;
 subplot(1,2,1)
 contourf(X_G2,Y_G2,Z_G_CALC5)
 colormap jet
h=colorbar;
ylabel(h,'mgal')
title('ESTIMATED GRAVITY DATA')
xlabel('x-unit')
ylabel('y-unit')
set(gca,'fontsize',20)
subplot(1,2,2)
set(gca,'fontsize',20)
contourf(X_G,Y_G,Z_G)
colormap jet
h=colorbar;
ylabel(h,'mgal')
title('OBSERVED GRAVITY DATA')
xlabel('x-unit')
ylabel('y-unit')
set(gca,'fontsize',20)

rho_ini=Z_G./(0.04191*(Z_D+Z_D.*uncertainty./100));
if app.NegativedensitycontrastCheckBox.Value == true;
    figure;
contourf(X_G,Y_G,-1.*(2.*rho_ini-min(2.*rho_ini)))
colormap jet
colorbar
title('Initial Computed Density Contrast')
xlabel('x-unit')
ylabel('y-unit')
set(gca,'fontsize',20)
else
figure;
contourf(X_G,Y_G,2.*rho_ini-min(2.*rho_ini))
colormap jet
colorbar
title('Initial Computed Density Contrast')
xlabel('x-unit')
ylabel('y-unit')
set(gca,'fontsize',20)
end


rho_E=Z_G_CALC5./(0.04191*(Z_D+Z_D.*uncertainty./100));
if app.NegativedensitycontrastCheckBox.Value == true;
 figure;
 contourf(X_G2,Y_G2,-1.*(2.*rho_E-2.*rho_E.*uncertainty./100-min(2.*rho_E-2.*rho_E.*uncertainty./100)))
 colormap jet
colorbar
title('Estimated Final Density Contrast')
xlabel('x-unit')
ylabel('y-unit')
set(gca,'fontsize',20)
else
     figure;
 contourf(X_G2,Y_G2,2.*rho_E-2.*rho_E.*uncertainty./100-min(2.*rho_E-2.*rho_E.*uncertainty./100))
 colormap jet
colorbar
title('Estimated Final Density Contrast')
xlabel('x-unit')
ylabel('y-unit')
set(gca,'fontsize',20)
end

            else
             f=errordlg('ERROR')
             d=errordlg(num2str(Control),'Control') %Problemi gormek icin yaptim
           end
        end

        % Button pushed function: BrowseButton
        function browse(app, event)
                        [file,path]=uigetfile('*.dat');
            
            if isequal(file,0)
                f=errordlg('You have not selected a dat file','Warning!')
            else
                %disp(['User selected', fullfile(path,file)]);
                app.GravityDataEditField.Value=file;
                app.FILE1=fullfile(path,file);
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GravityDataEditFieldLabel = uilabel(app.UIFigure);
            app.GravityDataEditFieldLabel.Text = 'Gravity Data';
            app.GravityDataEditField = uieditfield(app.UIFigure, 'text');
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @browse, true);
            app.BrowseButton.Text = {'Browse'; ''};
            app.dyEditFieldLabel = uilabel(app.UIFigure);
            app.dyEditFieldLabel.Text = 'dy';
            app.dyEditField = uieditfield(app.UIFigure, 'text');
            app.dxEditField_2Label = uilabel(app.UIFigure);
            app.dxEditField_2Label.Text = 'dx';
            app.dxEditField_2 = uieditfield(app.UIFigure, 'text');
            app.SlabThicknesskmEditFieldLabel = uilabel(app.UIFigure);
            app.SlabThicknesskmEditFieldLabel.Text = 'Slab Thickness (km)';
            app.SlabThicknesskmEditField = uieditfield(app.UIFigure, 'text');
            app.UncertaintyPercentageEditFieldLabel = uilabel(app.UIFigure);
            app.UncertaintyPercentageEditFieldLabel.Text = 'Uncertainty (Percentage %)';
            app.UncertaintyPercentageEditField = uieditfield(app.UIFigure, 'text');
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @runbutton, true);
            app.RunButton.Text = {'Run'; ''};
            app.NegativedensitycontrastCheckBox = uicheckbox(app.UIFigure);
            app.NegativedensitycontrastCheckBox.Text = 'Negative density contrast';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ilk_inv_gui_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end